classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FPSSliderLabel         matlab.ui.control.Label
        FPSSlider              matlab.ui.control.Slider
        LeftWheelSliderLabel   matlab.ui.control.Label
        LeftWheelSlider        matlab.ui.control.Slider
        RightWheelSliderLabel  matlab.ui.control.Label
        RightWheelSlider       matlab.ui.control.Slider
        UIAxes                 matlab.ui.control.UIAxes
        runSwitchLabel         matlab.ui.control.Label
        runSwitch              matlab.ui.control.ToggleSwitch
        TaskSwitchLabel        matlab.ui.control.Label
        TaskSwitch             matlab.ui.control.Switch
    end

    
    properties (Access = private)
        myTimer % Description
        grid % Description
        robot_direction = 0 % Description
        robot_position_vector = [115 106];
        light_position = [910 547];
%         simulinkus = myModel1;
        dimX = 1000;
        dimY = 663;
        localtime = 1:10;
        LogicalStr = {'false', 'true'};
    end
    
    
    methods (Access = private)
        
        function timer_callback_fcn(app)
            disp(app.robot_direction(app.robot_direction_index)); 
            
            
            toShow = boolean(app.grid + putRobot(app.grid, app.robot_position_vector, round(app.robot_direction(app.robot_direction_index))));
            app.robot_direction_index = app.robot_direction_index+1;
%             toShow = boolean(app.grid);
            
            toShow = 1-flipud(transpose(toShow));
            imshow(toShow, 'Parent',app.UIAxes);
            
            app.localtime = app.localtime + round(100/app.FPSSlider.Value)/100;
        end
        
        function task1loop(app)
            x = app.robot_position_vector(1);
            y = app.robot_position_vector(2);
            o = app.robot_direction*pi/180;
            t = round(100/app.FPSSlider.Value)/100 * 2;
            
            speedLeft = app.LeftWheelSlider.Value;
            speedRight = app.RightWheelSlider.Value;
            
            fprintf("\npos [%2.1f %2.1f] dir [%2.1f] dt [%2.2f]\n\tspd [%2.2f %2.2f]", ...
                x,y,o,t, speedLeft, speedRight)
            
            v = (speedRight + speedLeft)/2;
            w = (speedRight - speedLeft)/104;
            
            x = x + v*cos(w)*t;
            y = y + v*sin(w)*t;
            o = o + w*t;
            
            app.robot_position_vector = checkBorders([x y]);
            app.robot_direction = o*180/pi;
            
            toShow = boolean(app.grid + putRobot(app.grid, round(app.robot_position_vector), app.robot_direction));
            toShow = 1-flipud(transpose(toShow));
            imshow(toShow, 'Parent',app.UIAxes);
        end        
        
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("helper/");
            
            app.grid = boolean(zeros(app.dimX, app.dimY)); % in mm
            app.grid = drawSq(app.grid, 1,1,app.dimX, app.dimY,1);
            
            app.robot_position_vector = [500 300];
            
            toShow = boolean(app.grid + putRobot(app.grid, app.robot_position_vector, round(app.robot_direction)));
            
            toShow = 1-flipud(transpose(toShow));
            imshow(toShow, 'Parent',app.UIAxes);
            
            app.myTimer = timer('Period', round(100/app.FPSSlider.Value)/100, 'ExecutionMode', 'fixedSpacing','StartDelay',3);
            fprintf('\ntimer is set to %f\n', round(100/app.FPSSlider.Value)/100);
            app.myTimer.TimerFcn = @(~,~)app.task1loop;
            start(app.myTimer);
        end

        % Value changed function: runSwitch
        function runSwitchValueChanged(app, event)
            value = app.runSwitch.Value;
            disp(value);
            if(value=="On")
                app.myTimer = timer('Period', round(100/app.FPSSlider.Value)/100, 'ExecutionMode', 'fixedSpacing');
                disp('timer is set to' + round(100/app.FPSSlider.Value)/100);
                app.myTimer.TimerFcn = @(~,~)app.timer_callback_fcn;
                
                assignin("base", "timeIn", app.localtime);
                assignin("base", "lightPos", app.light_position);
                assignin("base", "robotPos", app.robot_position_vector);
%                 assignin("base", "timeIn", app.localtime);
                
%                 app.robot_position_vector = [115 106];
                simData = sim("myModel1.slx");
                app.robot_direction = simData.direction.Data;
                app.robot_position_vector = simData.position.Data(1);
                disp(size(app.robot_direction));
                
                start(app.myTimer)
            else
                disp('deleting');
                delete(app.myTimer)
            end
        end

        % Value changed function: FPSSlider
        function fpsChange(app, event)
            disp('chaging fps');
            delete(app.myTimer)
            app.runSwitch.Value="Off";
            
            if(app.TaskSwitch.Value == "1")
                app.myTimer = timer('Period', round(100/app.FPSSlider.Value)/100, 'ExecutionMode', 'fixedSpacing','StartDelay',1);
                fprintf('\ntimer is set to %f\n', round(100/app.FPSSlider.Value)/100);
                app.myTimer.TimerFcn = @(~,~)app.task1loop;
                start(app.myTimer);
            end
        end

        % Value changed function: TaskSwitch
        function changeTask(app, event)
            delete(app.myTimer)
            
            app.TaskSwitch.Enable = false;
            dimY = 663; dimX = 1000;
            app.grid = boolean(zeros(dimX,dimY));
            app.LeftWheelSlider.Enable = false;
            app.RightWheelSlider.Enable = false;
            app.runSwitch.Enable = true;
            
            app.grid = boolean(app.grid + putObstacle(dimX, dimY, 308, 264, 60));
            app.grid = boolean(app.grid + putObstacle(dimX, dimY, 267, 539, 60));
            app.grid = boolean(app.grid + putObstacle(dimX, dimY, 684, 279, 60));
            app.grid = boolean(app.grid + putObstacle(dimX, dimY, 902, 100, 60));
            app.grid = boolean(app.grid + putObstacle(dimX, dimY, 910, 547, 10));
            
            %910 547;
            stgz = 200;
            [gray,colord] = drawGrad(dimX, dimY, app.light_position(1), app.light_position(2), stgz);
            

            toShow = 1-flipud(transpose(app.grid));
            
            
            % bg image
            [img, ~, alphachannel] = imread("map.png");
            image(img, 'AlphaData', 0.3*alphachannel, 'Parent', app.UIAxes);
            
            hold(app.UIAxes , "on");
            
            % the light suorce
            imgz = imshow(colord, 'Parent',app.UIAxes);
            set(imgz, 'AlphaData', stgz-gray);
            
            obsticles = imshow(toShow, 'Parent',app.UIAxes);
            set(obsticles, 'AlphaData', 1-toShow);
            hold(app.UIAxes , "off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FPSSliderLabel = uilabel(app.UIFigure);
            app.FPSSliderLabel.Text = 'FPS';
            app.FPSSlider = uislider(app.UIFigure);
            app.FPSSlider.Limits = [5 40];
            app.FPSSlider.MajorTicks = [5 10 15 20 25 30 35 40];
            app.FPSSlider.ValueChangedFcn = createCallbackFcn(app, @fpsChange, true);
            app.FPSSlider.MinorTicks = [5 10 15 20 25 30 35 40];
            app.FPSSlider.Value = 15;
            app.LeftWheelSliderLabel = uilabel(app.UIFigure);
            app.LeftWheelSliderLabel.Text = 'Left Wheel';
            app.LeftWheelSlider = uislider(app.UIFigure);
            app.LeftWheelSlider.Limits = [-100 100];
            app.LeftWheelSlider.MinorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.RightWheelSliderLabel = uilabel(app.UIFigure);
            app.RightWheelSliderLabel.Text = 'Right Wheel';
            app.RightWheelSlider = uislider(app.UIFigure);
            app.RightWheelSlider.Limits = [-100 100];
            app.RightWheelSlider.MinorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'sosni huitsa')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.runSwitchLabel = uilabel(app.UIFigure);
            app.runSwitchLabel.Enable = 'off';
            app.runSwitchLabel.Text = 'run';
            app.runSwitch = uiswitch(app.UIFigure, 'toggle');
            app.runSwitch.ValueChangedFcn = createCallbackFcn(app, @runSwitchValueChanged, true);
            app.runSwitch.Enable = 'off';
            app.TaskSwitchLabel = uilabel(app.UIFigure);
            app.TaskSwitchLabel.Text = 'Task';
            app.TaskSwitch = uiswitch(app.UIFigure, 'slider');
            app.TaskSwitch.Items = {'1', '2'};
            app.TaskSwitch.ValueChangedFcn = createCallbackFcn(app, @changeTask, true);
            app.TaskSwitch.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end